classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        NewPipelineMenu          matlab.ui.container.Menu
        LoadPipelineMenu         matlab.ui.container.Menu
        SavePipelineMenu         matlab.ui.container.Menu
        ExitMenu                 matlab.ui.container.Menu
        OpenMenu                 matlab.ui.container.Menu
        SpreadsheetLocationMenu  matlab.ui.container.Menu
        OutputFolderMenu         matlab.ui.container.Menu
        FiguresMenu              matlab.ui.container.Menu
        RawDataFolderMenu        matlab.ui.container.Menu
        SurfNIRSMenu             matlab.ui.container.Menu
        HelpMenu                 matlab.ui.container.Menu
        GitHubMenu               matlab.ui.container.Menu
        RightPanel               matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        OpenLogButton            matlab.ui.control.Button
        ClearLogButton           matlab.ui.control.Button
        LogTextArea              matlab.ui.control.TextArea
        LeftPanel                matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        StopButton               matlab.ui.control.Button
        PipelineLabel            matlab.ui.control.Label
        StepsScrollPanel         matlab.ui.container.Panel
        OverwriteSwitch          matlab.ui.control.Switch
        OverwriteLabel           matlab.ui.control.Label
        RunAllButton             matlab.ui.control.Button
    end

    properties (Access = public)
        Pipeline % GUIRestingState.Pipeline
        LogFilePath              string = ""
        StepHandles              struct = struct([])
        StepsGrid                matlab.ui.container.GridLayout
        GenAllFigsLabel          matlab.ui.control.Label
        GenAllFigsSwitch         matlab.ui.control.Switch
        HighResCheckbox          matlab.ui.control.CheckBox
        PhaseColors
        DirtyColor
        OtherColors
        StopRequested            logical = false
        IsRunning                logical = false
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Redirect to external startup script
            GUIRestingState.startup(app);
        end

        % Menu selected function: NewPipelineMenu
        function NewPipelineMenuSelected(app, event)
            GUIRestingState.Callbacks.NewPipelineMenuSelected(app, event);
        end

        % Menu selected function: LoadPipelineMenu
        function LoadPipelineMenuSelected(app, event)
            GUIRestingState.Callbacks.LoadPipelineMenuSelected(app, event);
        end

        % Menu selected function: SavePipelineMenu
        function SavePipelineMenuSelected(app, event)
            GUIRestingState.Callbacks.SavePipelineMenuSelected(app, event);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            GUIRestingState.Callbacks.ExitMenuSelected(app, event);
        end

        % Menu selected function: SpreadsheetLocationMenu
        function SpreadsheetLocationMenuSelected(app, event)
            GUIRestingState.Callbacks.OpenSpreadsheetPushed(app, event);
        end

        % Menu selected function: OutputFolderMenu
        function OutputFolderMenuSelected(app, event)
            GUIRestingState.Callbacks.OpenOutputFolderButtonPushed(app, event);
        end

        % Menu selected function: FiguresMenu
        function FiguresMenuSelected(app, event)
            GUIRestingState.Callbacks.OpenFiguresFolderButtonPushed(app, event);
        end

        % Menu selected function: RawDataFolderMenu
        function RawDataFolderMenuSelected(app, event)
            GUIRestingState.Callbacks.OpenRawFolderButtonPushed(app, event);
        end

        % Menu selected function: GitHubMenu
        function GitHubMenuSelected(app, event)
            GUIRestingState.Callbacks.OpenGitHubSelected(app, event);
        end

        % Value changed function: OverwriteSwitch
        function OverwriteSwitchValueChanged(app, event)
            GUIRestingState.Callbacks.OverwriteSwitchChanged(app, event);
        end

        % Button pushed function: RunAllButton
        function RunAllButtonPushed(app, event)
            GUIRestingState.Callbacks.RunAllPushed(app, event);
        end

        % Button pushed function: ClearLogButton
        function ClearLogButtonPushed(app, event)
            GUIRestingState.Callbacks.ClearLogPushed(app, event);
        end

        % Button pushed function: OpenLogButton
        function OpenLogButtonPushed(app, event)
            GUIRestingState.Callbacks.OpenLogFilePushed(app, event);                   
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            GUIRestingState.Callbacks.StopPushed(app, event);
        end

        % Menu selected function: SurfNIRSMenu
        function SurfNIRSMenuSelected(app, event)
            GUIRestingState.Callbacks.OpenSurfNIRSSelected(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'fNIRS Preprocessing GUI';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewPipelineMenu = uimenu(app.FileMenu);
            app.NewPipelineMenu.MenuSelectedFcn = createCallbackFcn(app, @NewPipelineMenuSelected, true);
            app.NewPipelineMenu.Text = 'New Pipeline';
            app.LoadPipelineMenu = uimenu(app.FileMenu);
            app.LoadPipelineMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPipelineMenuSelected, true);
            app.LoadPipelineMenu.Text = 'Load Pipeline';
            app.SavePipelineMenu = uimenu(app.FileMenu);
            app.SavePipelineMenu.MenuSelectedFcn = createCallbackFcn(app, @SavePipelineMenuSelected, true);
            app.SavePipelineMenu.Text = 'Save Pipeline';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.OpenMenu = uimenu(app.UIFigure);
            app.OpenMenu.Text = 'Open';
            app.SpreadsheetLocationMenu = uimenu(app.OpenMenu);
            app.SpreadsheetLocationMenu.MenuSelectedFcn = createCallbackFcn(app, @SpreadsheetLocationMenuSelected, true);
            app.SpreadsheetLocationMenu.Text = 'Spreadsheet Location';
            app.OutputFolderMenu = uimenu(app.OpenMenu);
            app.OutputFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @OutputFolderMenuSelected, true);
            app.OutputFolderMenu.Text = 'Output Folder';
            app.FiguresMenu = uimenu(app.OpenMenu);
            app.FiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @FiguresMenuSelected, true);
            app.FiguresMenu.Text = 'Figures';
            app.RawDataFolderMenu = uimenu(app.OpenMenu);
            app.RawDataFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @RawDataFolderMenuSelected, true);
            app.RawDataFolderMenu.Text = 'Raw Data Folder';
            app.SurfNIRSMenu = uimenu(app.OpenMenu);
            app.SurfNIRSMenu.MenuSelectedFcn = createCallbackFcn(app, @SurfNIRSMenuSelected, true);
            app.SurfNIRSMenu.Text = 'SurfNIRS';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.GitHubMenu = uimenu(app.HelpMenu);
            app.GitHubMenu.MenuSelectedFcn = createCallbackFcn(app, @GitHubMenuSelected, true);
            app.GitHubMenu.Text = 'GitHub';
            app.LeftPanel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.LeftPanel);
            app.RunAllButton = uibutton(app.GridLayout, 'push');
            app.RunAllButton.ButtonPushedFcn = createCallbackFcn(app, @RunAllButtonPushed, true);
            app.RunAllButton.Text = 'Run All';
            app.OverwriteLabel = uilabel(app.GridLayout);
            app.OverwriteLabel.Visible = 'off';
            app.OverwriteLabel.Text = 'Overwrite';
            app.OverwriteSwitch = uiswitch(app.GridLayout, 'slider');
            app.OverwriteSwitch.ValueChangedFcn = createCallbackFcn(app, @OverwriteSwitchValueChanged, true);
            app.OverwriteSwitch.Enable = 'off';
            app.OverwriteSwitch.Visible = 'off';
            app.OverwriteSwitch.Value = 'On';
            app.StepsScrollPanel = uipanel(app.GridLayout);
            app.StepsScrollPanel.Title = 'Steps';
            app.PipelineLabel = uilabel(app.GridLayout);
            app.PipelineLabel.Text = 'Pipeline:';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.RightPanel = uipanel(app.UIFigure);
            app.RightPanel.Title = 'Execution Log';
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.LogTextArea = uitextarea(app.GridLayout2);
            app.ClearLogButton = uibutton(app.GridLayout2, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogButtonPushed, true);
            app.ClearLogButton.Text = 'Clear Log';
            app.OpenLogButton = uibutton(app.GridLayout2, 'push');
            app.OpenLogButton.ButtonPushedFcn = createCallbackFcn(app, @OpenLogButtonPushed, true);
            app.OpenLogButton.Text = 'Open Log file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end